classdef MotionStim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TestTrialButton             matlab.ui.control.Button
        SpeedMotorControlPanel      matlab.ui.container.Panel
        STOPButton                  matlab.ui.control.Button
        TestSpeedButton             matlab.ui.control.Button
        DurationsecSpinner          matlab.ui.control.Spinner
        DurationsecSpinnerLabel     matlab.ui.control.Label
        DirectionCheckBox           matlab.ui.control.CheckBox
        SpeedSpinner                matlab.ui.control.Spinner
        SpeedSpinnerLabel           matlab.ui.control.Label
        StepperMotorControlPanel    matlab.ui.container.Panel
        Set90toFingerButton         matlab.ui.control.Button
        RotateCWButton              matlab.ui.control.Button
        RotateCCWButton             matlab.ui.control.Button
        StepSizeSpinner             matlab.ui.control.Spinner
        StepSizeSpinnerLabel        matlab.ui.control.Label
        RotationKnob                matlab.ui.control.Knob
        RotationKnobLabel           matlab.ui.control.Label
        MegaLamp                    matlab.ui.control.Lamp
        MegaLampLabel               matlab.ui.control.Label
        LeoLamp                     matlab.ui.control.Lamp
        LeoLampLabel                matlab.ui.control.Label
        LinearActuatorControlPanel  matlab.ui.container.Panel
        SetBaseButton               matlab.ui.control.Button
        ToDepthButton               matlab.ui.control.Button
        ToBaseButton                matlab.ui.control.Button
        Slider                      matlab.ui.control.Slider
        SliderLabel                 matlab.ui.control.Label
        MotionStimulusLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        serialLA; % serial connection to linear actuator (LEO Board)
        serialSM; % serial connection to stepper motor / herkulex (MEGA Board) 

        % Linear Actuator
        BasePosition;
        DepthPosition;
        depthCommandLabel = 1; 

        % Rotating Stepper Motor
        currentAngle; 
        rotateCommandLabel = 6; 

        % Herkulex
        speedCommandLabel = 2; % for a trial
        speedTestCommandLabel = 3; % for testing speed 

    end
    
    methods (Access = private)
        
        function sendMtrCommand(app,commandLabel, moveAmount)
            if commandLabel == app.depthCommandLabel % move linear actuator 
                serialCommand = sprintf("%d\n", [commandLabel moveAmount]);
                write(app.serialLA, serialCommand, "string"); 

            elseif commandLabel == app.rotateCommandLabel % rotate stepper motor 
                if moveAmount >= 0
                    direction = 1; % move CCW 
                else 
                    direction = 0; % move CW
                end
                mtrNum = 1; 
                serialCommand = sprintf("%d\n%d\n%d\n%.2f\n", [commandLabel mtrNum direction abs(moveAmount)]); 
                write(app.serialSM, serialCommand, "string");

            elseif commandLabel == app.speedCommandLabel % speed herkulex - trial control 
                duration = app.DurationsecSpinner.Value * 1000;
                setSpeed = moveAmount; 
                serialCommand = sprintf("%d\n%d\n%d\n", [commandLabel setSpeed duration]); 
                write(app.serialSM, serialCommand, "string");  

            elseif commandLabel == app.speedTestCommandLabel % speed without duration
                setSpeed = moveAmount;
                serialCommand = sprintf("%d\n%d\n", [commandLabel setSpeed]); 
                write(app.serialSM, serialCommand, "string"); 
            end
        end

        function moveAngle = calcMoveAngle(app, angle)
            currentAngle = app.currentAngle; 
            moveAngle = angle - currentAngle;
            app.currentAngle = angle; 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Connect to LEO (Linear Actuator)
            try
                app.serialLA = serialport('COM5', 9600);
                configureTerminator(app.serialLA, "CR/LF");
                set(app.serialLA, 'Timeout',15); 
                app.LeoLamp.Color = [0 1 0];
            catch
                app.LeoLamp.Color = [1 0 0];
            end

            % Connect to MEGA (Stepper Motor / Herkulex)
            try
                app.serialSM = serialport('COM3', 115200);
                configureTerminator(app.serialSM, "CR/LF");
                set(app.serialSM, 'Timeout',15); 
                app.MegaLamp.Color = [0 1 0];
            catch
                app.MegaLamp.Color = [1 0 0];
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.sendMtrCommand(app.depthCommandLabel,value); 
        end

        % Button pushed function: SetBaseButton
        function SetBaseButtonPushed(app, event)
            app.BasePosition = app.Slider.Value - 1.5*46;
            app.DepthPosition = app.BasePosition + 2.5*46; 
        end

        % Button pushed function: ToBaseButton
        function ToBaseButtonPushed(app, event)
            app.sendMtrCommand(app.depthCommandLabel, app.BasePosition);
        end

        % Button pushed function: ToDepthButton
        function ToDepthButtonPushed(app, event)
            app.sendMtrCommand(app.depthCommandLabel, app.DepthPosition); 
        end

        % Button pushed function: Set90toFingerButton
        function Set90toFingerButtonPushed(app, event)
            app.currentAngle = 90; 
        end

        % Button pushed function: RotateCCWButton
        function RotateCCWButtonPushed(app, event)
            angle = app.StepSizeSpinner.Value;
            app.sendMtrCommand(app.rotateCommandLabel, angle); 
        end

        % Button pushed function: RotateCWButton
        function RotateCWButtonPushed(app, event)
            angle = app.StepSizeSpinner.Value;
            app.sendMtrCommand(app.rotateCommandLabel, -angle); 
        end

        % Value changed function: RotationKnob
        function RotationKnobValueChanged(app, event)
            targetAngle = app.RotationKnob.Value;
            moveAngle = app.calcMoveAngle(targetAngle); 
            app.sendMtrCommand(app.rotateCommandLabel, moveAngle); 
        end

        % Button pushed function: TestSpeedButton
        function TestSpeedButtonPushed(app, event)
            setSpeed = app.SpeedSpinner.Value;
            if app.DirectionCheckBox.Value
                direction = 1;
            else
                direction = -1; 
            end
            app.sendMtrCommand(app.speedTestCommandLabel, setSpeed * direction); 
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            setSpeed = 0;
            app.sendMtrCommand(app.speedTestCommandLabel, setSpeed); 
        end

        % Button pushed function: TestTrialButton
        function TestTrialButtonPushed(app, event)
            % Start Rotating Herkulex
            setSpeed = app.SpeedSpinner.Value;
            if app.DirectionCheckBox.Value
                direction = 1;
            else
                direction = -1; 
            end
            app.sendMtrCommand(app.speedTestCommandLabel, setSpeed * direction); 

            % Move Linear Actuator to Depth
            app.sendMtrCommand(app.depthCommandLabel, app.DepthPosition); 

            pause(app.DurationsecSpinner.Value);

            % Move Linear Actuator to Base
            app.sendMtrCommand(app.depthCommandLabel, app.BasePosition); 

            pause(0.5); 

            % Stop Herkulex 
            app.sendMtrCommand(app.speedTestCommandLabel, 0); 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotionStimulusLabel = uilabel(app.UIFigure);
            app.MotionStimulusLabel.Text = 'Motion Stimulus ';
            app.LinearActuatorControlPanel = uipanel(app.UIFigure);
            app.LinearActuatorControlPanel.Title = 'Linear Actuator Control';
            app.SliderLabel = uilabel(app.LinearActuatorControlPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LinearActuatorControlPanel);
            app.Slider.Limits = [900 2100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 900;
            app.ToBaseButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.ToBaseButton.ButtonPushedFcn = createCallbackFcn(app, @ToBaseButtonPushed, true);
            app.ToBaseButton.Text = 'To Base';
            app.ToDepthButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.ToDepthButton.ButtonPushedFcn = createCallbackFcn(app, @ToDepthButtonPushed, true);
            app.ToDepthButton.Text = 'To Depth';
            app.SetBaseButton = uibutton(app.LinearActuatorControlPanel, 'push');
            app.SetBaseButton.ButtonPushedFcn = createCallbackFcn(app, @SetBaseButtonPushed, true);
            app.SetBaseButton.Text = 'Set Base';
            app.LeoLampLabel = uilabel(app.UIFigure);
            app.LeoLampLabel.Text = 'Leo';
            app.LeoLamp = uilamp(app.UIFigure);
            app.MegaLampLabel = uilabel(app.UIFigure);
            app.MegaLampLabel.Text = 'Mega';
            app.MegaLamp = uilamp(app.UIFigure);
            app.StepperMotorControlPanel = uipanel(app.UIFigure);
            app.StepperMotorControlPanel.Title = 'Stepper Motor Control ';
            app.RotationKnobLabel = uilabel(app.StepperMotorControlPanel);
            app.RotationKnobLabel.Text = 'Rotation';
            app.RotationKnob = uiknob(app.StepperMotorControlPanel, 'continuous');
            app.RotationKnob.Limits = [0 360];
            app.RotationKnob.ValueChangedFcn = createCallbackFcn(app, @RotationKnobValueChanged, true);
            app.StepSizeSpinnerLabel = uilabel(app.StepperMotorControlPanel);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.StepSizeSpinner = uispinner(app.StepperMotorControlPanel);
            app.StepSizeSpinner.Step = 0.05625;
            app.StepSizeSpinner.Limits = [0 360];
            app.RotateCCWButton = uibutton(app.StepperMotorControlPanel, 'push');
            app.RotateCCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCCWButtonPushed, true);
            app.RotateCCWButton.Text = 'Rotate CCW';
            app.RotateCWButton = uibutton(app.StepperMotorControlPanel, 'push');
            app.RotateCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCWButtonPushed, true);
            app.RotateCWButton.Text = 'Rotate CW';
            app.Set90toFingerButton = uibutton(app.StepperMotorControlPanel, 'push');
            app.Set90toFingerButton.ButtonPushedFcn = createCallbackFcn(app, @Set90toFingerButtonPushed, true);
            app.Set90toFingerButton.Text = 'Set 90 to Finger';
            app.SpeedMotorControlPanel = uipanel(app.UIFigure);
            app.SpeedMotorControlPanel.Title = 'Speed Motor Control ';
            app.SpeedSpinnerLabel = uilabel(app.SpeedMotorControlPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.SpeedMotorControlPanel);
            app.SpeedSpinner.Step = 10;
            app.SpeedSpinner.Limits = [0 1000];
            app.SpeedSpinner.Value = 100;
            app.DirectionCheckBox = uicheckbox(app.SpeedMotorControlPanel);
            app.DirectionCheckBox.Text = 'Direction';
            app.DurationsecSpinnerLabel = uilabel(app.SpeedMotorControlPanel);
            app.DurationsecSpinnerLabel.Text = 'Duration (sec)';
            app.DurationsecSpinner = uispinner(app.SpeedMotorControlPanel);
            app.DurationsecSpinner.Step = 0.25;
            app.DurationsecSpinner.Limits = [0 10];
            app.DurationsecSpinner.Value = 1;
            app.TestSpeedButton = uibutton(app.SpeedMotorControlPanel, 'push');
            app.TestSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @TestSpeedButtonPushed, true);
            app.TestSpeedButton.Text = 'Test Speed';
            app.STOPButton = uibutton(app.SpeedMotorControlPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TestTrialButton = uibutton(app.UIFigure, 'push');
            app.TestTrialButton.ButtonPushedFcn = createCallbackFcn(app, @TestTrialButtonPushed, true);
            app.TestTrialButton.Text = 'Test Trial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionStim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end